classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        DataPanel                      matlab.ui.container.Panel
        CmzeroEditFieldLabel           matlab.ui.control.Label
        CmzeroEditField                matlab.ui.control.NumericEditField
        ClzeroEditFieldLabel           matlab.ui.control.Label
        ClzeroEditField                matlab.ui.control.NumericEditField
        ClEditFieldLabel               matlab.ui.control.Label
        ClEditField                    matlab.ui.control.NumericEditField
        CmEditFieldLabel               matlab.ui.control.Label
        CmEditField                    matlab.ui.control.NumericEditField
        CliHEditFieldLabel             matlab.ui.control.Label
        CliHEditField                  matlab.ui.control.NumericEditField
        CmiHEditFieldLabel             matlab.ui.control.Label
        CmiHEditField                  matlab.ui.control.NumericEditField
        CldeltaelevatorEditFieldLabel  matlab.ui.control.Label
        CldeltaelevatorEditField       matlab.ui.control.NumericEditField
        CmdeltaelevatorEditFieldLabel  matlab.ui.control.Label
        CmdeltaelevatorEditField       matlab.ui.control.NumericEditField
        iHEditFieldLabel               matlab.ui.control.Label
        iHEditField                    matlab.ui.control.NumericEditField
        XcgEditFieldLabel              matlab.ui.control.Label
        XcgEditField                   matlab.ui.control.NumericEditField
        Tab2                           matlab.ui.container.Tab
        Tab3                           matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        CGpositionSliderLabel          matlab.ui.control.Label
        CGpositionSlider               matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
                
        function TrimCurveInit(app)
            app.CmzeroEditField.Value = 0; % distance from real cg , delta_x_cg = now cg - cg
        end
        function TrimPloter(app)
            %%% iH %%%
            iH = app.iHEditField.Value;
            %%% CL elevator %%%
            Cl_delta_elevator = app.CldeltaelevatorEditField.Value;
            %%% Cm elevator %%%
            Cm_delta_elevator = app.CmdeltaelevatorEditField.Value;
            %%% Cl zero %%%
            Cl_zero = 0.21;
            %%% Cm zero %%%
            Cm_zero = 0.0;
            %%% Cl alpha %%%
            Cl_alpha = 4.4; % Rad
            %%% Cm alpha %%%
            Cm_alpha = -1.00;
            %%% Cl iH %%%
            Cl_iH = 0.70; % Rad
            %%% Cm iH %%%
            Cm_iH = -2.7;
            %%% Cm_alpha_cl_alpha %%%
            Cm_alpha_cl_alph = (Cm_alpha / Cl_alpha) +...
                (app.CGpositionSlider.Value - app.XcgEditField.Value);
            %%% Cm zero bar %%%
            Cm_zero_bar = Cm_zero - Cm_alpha_cl_alph * Cl_zero;
            %%% Cm delta elevator bar %%%
            Cm_delta_elevator_bar = Cm_delta_elevator - Cm_alpha_cl_alph * Cl_delta_elevator;
            %%% Cm iH bar %%%
            Cm_iH_bar = Cm_iH - Cm_alpha_cl_alph * Cl_iH;
            delta_elevator = -30:5:15; %deg
            delta_elevator = delta_elevator * pi / 180; %rad
            Cl = 0:.1:3;
            hold(app.UIAxes,"off");
            for i = 1:length(delta_elevator)
                Cm = Cm_zero_bar + (Cm_alpha / Cl_alpha) * Cl +...
                Cm_delta_elevator_bar * delta_elevator(i) + Cm_iH_bar*iH;
                plot(app.UIAxes,Cm,Cl)
                hold(app.UIAxes,"on");
            end
            set(app.UIAxes, 'xdir', 'reverse' );
            xlabel(app.UIAxes, '$C_m$','Interpreter','latex');
            ylabel(app.UIAxes, '$C_l$','Interpreter','latex');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            seven_four_seven_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.CGpositionSlider.Value = x_cg;
        end

        % Value changed function: CmzeroEditField
        function CmzeroEditFieldValueChanged(app, event)

        end

        % Size changed function: DataPanel
        function DataPanelSizeChanged(app, event)

        end

        % Value changed function: CGpositionSlider
        function CGpositionSliderValueChanged(app, event)
            app.TrimPloter;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '747';
            app.DataPanel = uipanel(app.Tab);
            app.DataPanel.Title = 'Data';
            app.DataPanel.SizeChangedFcn = createCallbackFcn(app, @DataPanelSizeChanged, true);
            app.CmzeroEditFieldLabel = uilabel(app.DataPanel);
            app.CmzeroEditFieldLabel.Text = 'Cm zero';
            app.CmzeroEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmzeroEditField.ValueChangedFcn = createCallbackFcn(app, @CmzeroEditFieldValueChanged, true);
            app.ClzeroEditFieldLabel = uilabel(app.DataPanel);
            app.ClzeroEditFieldLabel.Text = 'Cl zero';
            app.ClzeroEditField = uieditfield(app.DataPanel, 'numeric');
            app.ClEditFieldLabel = uilabel(app.DataPanel);
            app.ClEditFieldLabel.Text = 'Cl α';
            app.ClEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmEditFieldLabel = uilabel(app.DataPanel);
            app.CmEditFieldLabel.Text = 'Cm α';
            app.CmEditField = uieditfield(app.DataPanel, 'numeric');
            app.CliHEditFieldLabel = uilabel(app.DataPanel);
            app.CliHEditFieldLabel.Text = 'Cl i H';
            app.CliHEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmiHEditFieldLabel = uilabel(app.DataPanel);
            app.CmiHEditFieldLabel.Text = 'Cm i H';
            app.CmiHEditField = uieditfield(app.DataPanel, 'numeric');
            app.CldeltaelevatorEditFieldLabel = uilabel(app.DataPanel);
            app.CldeltaelevatorEditFieldLabel.Text = 'Cl delta elevator';
            app.CldeltaelevatorEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmdeltaelevatorEditFieldLabel = uilabel(app.DataPanel);
            app.CmdeltaelevatorEditFieldLabel.Text = 'Cm delta elevator';
            app.CmdeltaelevatorEditField = uieditfield(app.DataPanel, 'numeric');
            app.iHEditFieldLabel = uilabel(app.DataPanel);
            app.iHEditFieldLabel.Text = 'i H';
            app.iHEditField = uieditfield(app.DataPanel, 'numeric');
            app.XcgEditFieldLabel = uilabel(app.DataPanel);
            app.XcgEditFieldLabel.Text = 'X cg';
            app.XcgEditField = uieditfield(app.DataPanel, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Image = uiimage(app.Tab3);
            app.Image.ImageSource = 'B747_005.jpg.0797481f913d7a3eaddf1ef973d948ff 2.jpg';
            app.CGpositionSliderLabel = uilabel(app.Tab3);
            app.CGpositionSliderLabel.Text = 'CG position';
            app.CGpositionSlider = uislider(app.Tab3);
            app.CGpositionSlider.Limits = [0 1];
            app.CGpositionSlider.ValueChangedFcn = createCallbackFcn(app, @CGpositionSliderValueChanged, true);
            app.UIAxes = uiaxes(app.Tab3);
            title(app.UIAxes, 'x')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end